classdef testgui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure         matlab.ui.Figure
        TabGroup         matlab.ui.container.TabGroup
        Tab              matlab.ui.container.Tab
        ValuePanel       matlab.ui.container.Panel
        I                matlab.ui.control.NumericEditField
        EditField3Label  matlab.ui.control.Label
        D                matlab.ui.control.NumericEditField
        EditField2Label  matlab.ui.control.Label
        P                matlab.ui.control.NumericEditField
        EditFieldLabel   matlab.ui.control.Label
        OptionsButton    matlab.ui.control.Button
        LampConnect      matlab.ui.control.Lamp
        Label_6          matlab.ui.control.Label
        SwitchConnect    matlab.ui.control.ToggleSwitch
        LampRejim3       matlab.ui.control.Lamp
        Label_5          matlab.ui.control.Label
        LampRejim2       matlab.ui.control.Lamp
        Label_4          matlab.ui.control.Label
        LampRejim1       matlab.ui.control.Lamp
        Label_3          matlab.ui.control.Label
        Knob             matlab.ui.control.Knob
        Go               matlab.ui.control.Lamp
        Label_2          matlab.ui.control.Label
        SwitchGo         matlab.ui.control.ToggleSwitch
        Gauge            matlab.ui.control.Gauge
        Tab_2            matlab.ui.container.Tab
        PlotPanel        matlab.ui.container.Panel
        PlotGo           matlab.ui.control.Button
        ValueGroup       matlab.ui.container.ButtonGroup
        Voltage          matlab.ui.control.RadioButton
        Current          matlab.ui.control.RadioButton
        Speed            matlab.ui.control.RadioButton
        Tab_3            matlab.ui.container.Tab
        UITable2         matlab.ui.control.Table
    end

    
    properties (Access = private)
       %Modbus
        flag = 0; % Description
        m; % Description
        registranalog;
        regim=2;
        flagerror=0;
        f;
        mutex;
        flagwrite=0;
        %
        
        %Grafik
        x_axis = [0,0];
        speed_A_axis = [0,0];
        update_period = 1;         % How often to update plot, points
        time_window = 10;           % Time window to be shown on figure, sec
        counter = 0;
        new_values = [];
        start_time = clock;
        flaggrafik=0;
        value=1;
        %
        DialogApp % Dialog box app 
    end
    
    methods (Access = private)
        
        function Modbus(app)
              while(app.flag)
                  while(app.mutex)
                  end
                  app.mutex=1;
                  zapros_analog=read(app.m,'holdingregs',1,50,'uint16');
                  zapros_disk=read(app.m,'coils',1,50);
                  app.mutex=0;
                  registrdisk=transpose(zapros_disk);
                  app.registranalog=transpose(zapros_analog);
                  app.UITable2.Data=([app.registranalog,registrdisk]);
                  app.Gauge.Value=app.registranalog(1);
                  pause(1)
              end
              while(app.mutex)
              end
              app.mutex=1;
              write(app.m,'coils',1,(1))
              app.mutex=0;
              clear m;

        end
        
        function GrafikData(app)
              while(app.flaggrafik)
              app.counter = app.counter + 1;
              app.new_values = app.registranalog(app.value);
              % Add new points to the plot and forget last one if necessary
              if app.x_axis(end) >= app.time_window
                 app.x_axis = [app.x_axis(2:end) etime(clock, app.start_time)];
                 app.speed_A_axis = [app.speed_A_axis(2:end) app.new_values ];
              else
                 app.x_axis = [app.x_axis etime(clock, app.start_time)];
                 app.speed_A_axis = [app.speed_A_axis app.new_values];
              end
              % Update plot
              if app.counter == app.update_period
                plot(app.Grafik,  app.x_axis,  app.speed_A_axis);
              if app.x_axis(end) >= app.time_window
                app.Grafik.XLim=[app.x_axis(1) app.x_axis(end)];
              end
              drawnow;
              app.counter = 0;
              end
              end
            
        end
        
    end
    methods (Access = public)
            function updataopt(app, regim, p, d, i, flag)
                app.P.Value=p;
                app.D.Value=d;
                app.I.Value=i;
                app.regim=regim;
                if app.regim==2
                    app.LampRejim1.Color='g'; 
                    app.LampRejim2.Color='r'; 
                    app.LampRejim3.Color='r'; 
                end
                if app.regim==3
                    app.LampRejim1.Color='r'; 
                    app.LampRejim2.Color='g'; 
                    app.LampRejim3.Color='r'; 
                end
                if app.regim==4
                    app.LampRejim1.Color='r'; 
                    app.LampRejim2.Color='r'; 
                    app.LampRejim3.Color='g'; 
                end
                app.flagwrite=flag;
                if(app.flagwrite)
                    app.Go.Color='g';
                end
                % Re-enable the Plot Options button
                app.OptionsButton.Enable = 'on';
            end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
             % Store main app object
              updataopt(app, app.regim, app.P.Value, app.D.Value, app.I.Value,0);
        end

        % Value changed function: SwitchGo
        function SwitchGoValueChanged(app, event)
         value = app.SwitchGo.Value;
            if strcmp(value,'Вкл')
                app.Go.Color='g';
                while(app.mutex)
                end
                app.mutex=1;
                write(app.m,'coils',1,(1));
                write(app.m,'coils',app.regim,(1))
                app.mutex=0;
            else
                app.Go.Color='r';
                while(app.mutex)
                end
                app.mutex=1;
                write(app.m,'coils',1,(0));
                app.mutex=0;
            end
            
        end

        % Value changed function: Knob
        function KnobValueChanged(app, event)
            value=app.Knob.Value;  
            if(app.flag==1)
                while(app.mutex)
                end
                app.mutex=1;
                write(app.m,'holdingregs',1,floor(value),'uint16')
                app.mutex=0;
            end
        end

        % Value changed function: SwitchConnect
        function SwitchConnectValueChanged(app, event)
            value = app.SwitchConnect.Value;
            if strcmp(value,'Вкл')
               try
                    app.m = modbus('tcpip', '192.168.1.123', 80,'Timeout',3);
               catch ME
                   if(strcmp(ME.identifier,'instrument:modbus:transportUnavailable'))                   
                        app.flagerror=1;
                        app.f=errordlg('Ошибка подключения: проверьте подключение микроконтроллера к компьютеру или параметры сети компьютера.','Ошбика подключения');
                        app.SwitchConnect.Value=('Выкл');
                   end
                end
                if app.flagerror==0
                    app.LampConnect.Color='g';
                    app.flag = 1;
                    Modbus(app)
                end
            else
                app.LampConnect.Color='r';
            end
        end

        % Selection changed function: ValueGroup
        function ValueGroupSelectionChanged(app, event)
            selectedButton = app.ValueGroup.SelectedObject;
            if selectedButton==app.Speed
                app.value=1;
                cla;
            end
            if selectedButton==app.Current
                app.value=2;
                cla;
            end
            if selectedButton==app.Voltage
                app.value=3;
                cla;
            end            
        end

        % Callback function
        function GrafikSwitchValueChanged(app, event)
            value = app.GrafikSwitch.Value;
            if strcmp(value,'Вкл')
                app.GrafikLamp.Color='g';
                app.flaggrafik=1;
                GrafikData(app)
            else
                app.GrafikLamp.Color='r';
                app.flaggrafik=0;
            end
        end

        % Button pushed function: OptionsButton
        function OptionsButtonPushed(app, event)
            % Disable Plot Options button while dialog is open
            app.OptionsButton.Enable = 'off';
            
            % Open the options dialog and pass inputs
            app.DialogApp = Dialogoptions(app, app.regim, app.P.Value, app.D.Value, app.I.Value,0);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app.DialogApp)
            delete(app)           
        end

        % Button pushed function: PlotGo
        function PlotGoButtonPushed(app, event)
            app.OptionsButton.Enable = 'off';
            
            % Open the options dialog and pass inputs
            app.DialogApp = grafikapp(app, app.value);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.TabGroup = uitabgroup(app.UIFigure);
            app.Tab = uitab(app.TabGroup);
            app.Tab.Title = 'Состояние системы';
            app.ValuePanel = uipanel(app.Tab);
            app.ValuePanel.Title = 'Состояние системы';
            app.Gauge = uigauge(app.ValuePanel, 'circular');
            app.Gauge.Limits = [0 1000];
            app.SwitchGo = uiswitch(app.ValuePanel, 'toggle');
            app.SwitchGo.Items = {'Выкл', 'Вкл'};
            app.SwitchGo.ValueChangedFcn = createCallbackFcn(app, @SwitchGoValueChanged, true);
            app.SwitchGo.Value = 'Выкл';
            app.Label_2 = uilabel(app.ValuePanel);
            app.Label_2.Text = {'Запуск'; ''};
            app.Go = uilamp(app.ValuePanel);
            app.Knob = uiknob(app.ValuePanel, 'continuous');
            app.Knob.Limits = [0 1000];
            app.Knob.MajorTicks = [0 100 200 300 400 500 600 700 800 900 1000];
            app.Knob.MajorTickLabels = {'0', '100', '200', '300', '400', '500', '600', '700', '800', '900', '1000'};
            app.Knob.ValueChangedFcn = createCallbackFcn(app, @KnobValueChanged, true);
            app.Label_3 = uilabel(app.ValuePanel);
            app.Label_3.Text = 'Режим 1';
            app.LampRejim1 = uilamp(app.ValuePanel);
            app.Label_4 = uilabel(app.ValuePanel);
            app.Label_4.Text = 'Режим 2';
            app.LampRejim2 = uilamp(app.ValuePanel);
            app.Label_5 = uilabel(app.ValuePanel);
            app.Label_5.Text = 'Режим 3';
            app.LampRejim3 = uilamp(app.ValuePanel);
            app.SwitchConnect = uiswitch(app.ValuePanel, 'toggle');
            app.SwitchConnect.Items = {'Выкл', 'Вкл'};
            app.SwitchConnect.ValueChangedFcn = createCallbackFcn(app, @SwitchConnectValueChanged, true);
            app.SwitchConnect.Value = 'Выкл';
            app.Label_6 = uilabel(app.ValuePanel);
            app.Label_6.Text = 'Подключение';
            app.LampConnect = uilamp(app.ValuePanel);
            app.OptionsButton = uibutton(app.ValuePanel, 'push');
            app.OptionsButton.ButtonPushedFcn = createCallbackFcn(app, @OptionsButtonPushed, true);
            app.OptionsButton.Text = 'Настройки';
            app.EditFieldLabel = uilabel(app.ValuePanel);
            app.EditFieldLabel.Text = 'Edit Field';
            app.P = uieditfield(app.ValuePanel, 'numeric');
            app.P.Editable = 'off';
            app.EditField2Label = uilabel(app.ValuePanel);
            app.EditField2Label.Text = 'Edit Field2';
            app.D = uieditfield(app.ValuePanel, 'numeric');
            app.D.Editable = 'off';
            app.EditField3Label = uilabel(app.ValuePanel);
            app.EditField3Label.Text = 'Edit Field3';
            app.I = uieditfield(app.ValuePanel, 'numeric');
            app.I.Editable = 'off';
            app.Tab_2 = uitab(app.TabGroup);
            app.Tab_2.Title = 'Графики';
            app.PlotPanel = uipanel(app.Tab_2);
            app.PlotPanel.Title = 'График';
            app.ValueGroup = uibuttongroup(app.PlotPanel);
            app.ValueGroup.SelectionChangedFcn = createCallbackFcn(app, @ValueGroupSelectionChanged, true);
            app.ValueGroup.Title = 'Выбор величины';
            app.Speed = uiradiobutton(app.ValueGroup);
            app.Speed.Text = 'Скорость';
            app.Speed.Value = true;
            app.Current = uiradiobutton(app.ValueGroup);
            app.Current.Text = 'Ток';
            app.Voltage = uiradiobutton(app.ValueGroup);
            app.Voltage.Text = 'Напряжение';
            app.PlotGo = uibutton(app.PlotPanel, 'push');
            app.PlotGo.ButtonPushedFcn = createCallbackFcn(app, @PlotGoButtonPushed, true);
            app.PlotGo.Text = 'НАЧАТЬ';
            app.Tab_3 = uitab(app.TabGroup);
            app.Tab_3.Title = 'Режим отладки';
            app.UITable2 = uitable(app.Tab_3);
            app.UITable2.ColumnName = {'Аналоговый регистры'; 'Дискретные регистры'};
            app.UITable2.RowName = {};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = testgui

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end